classdef GUI < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		UIFigure                matlab.ui.Figure
		FileMenu                matlab.ui.container.Menu
		OpenMenu                matlab.ui.container.Menu
		SaveMenu                matlab.ui.container.Menu
		EditMenu                matlab.ui.container.Menu
		FinishCheckBox          matlab.ui.control.CheckBox
		imgSlider               matlab.ui.control.Slider
		SelectImageSliderLabel  matlab.ui.control.Label
		selectImgDirBtn         matlab.ui.control.Button
		UIAxes                  matlab.ui.control.UIAxes
	end

	% Callbacks that handle component events
	methods (Access = private)

		% Callback function: OpenMenu, selectImgDirBtn
		function LoadDir(app, event)
			path = uigetdir();  % Select image directory
			files = dir(path);  % List all files in directory
			files = files(~[files.isdir]); % Remove subdirectories from list
			% Keep only .jpg files
			I = ~cellfun('isempty', regexp({files.name}, '.jpg$'));
			files = files(I);
			% Load all images
			imgs = cell(numel(files), 1);  % preallocate image storage
			for i = 1:numel(files)
				fpath = fullfile(files(i).folder, files(i).name);
				imgs{i} = imread(fpath);
			end
			% Display first image
			imagesc(app.UIAxes, imgs{1})
			% Set Image numbers in slider
			app.imgSlider.Limits = [1, numel(imgs)];
			app.imgSlider.Value = 1;
			app.imgSlider.MajorTicks = 1:numel(imgs);
			app.imgSlider.MinorTicks = [];
		end

		% Callback function
		function OpenImage(app, event)

		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			app.UIFigure = uifigure('Visible', 'off');
			app.UIFigure.Name = 'MATLAB App';
			app.FileMenu = uimenu(app.UIFigure);
			app.FileMenu.Text = 'File';
			app.OpenMenu = uimenu(app.FileMenu);
			app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadDir, true);
			app.OpenMenu.Text = 'Open';
			app.SaveMenu = uimenu(app.FileMenu);
			app.SaveMenu.Text = 'Save';
			app.EditMenu = uimenu(app.UIFigure);
			app.EditMenu.Text = 'Edit';
			app.UIAxes = uiaxes(app.UIFigure);
			title(app.UIAxes, 'Title')
			xlabel(app.UIAxes, 'X')
			ylabel(app.UIAxes, 'Y')
			zlabel(app.UIAxes, 'Z')
			app.selectImgDirBtn = uibutton(app.UIFigure, 'push');
			app.selectImgDirBtn.ButtonPushedFcn = createCallbackFcn(app, @LoadDir, true);
			app.selectImgDirBtn.Text = 'Select Image Directory';
			app.SelectImageSliderLabel = uilabel(app.UIFigure);
			app.SelectImageSliderLabel.Text = 'Select Image #';
			app.imgSlider = uislider(app.UIFigure);
			app.imgSlider.MinorTicks = [];
			app.FinishCheckBox = uicheckbox(app.UIFigure);
			app.FinishCheckBox.Text = 'Finish';
			% Show the figure after all components are created
			app.UIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = GUI

			% Create UIFigure and components
			createComponents(app)

			% Register the app with App Designer
			registerApp(app, app.UIFigure)

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.UIFigure)
		end
	end
end