classdef Version1Project1 < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    UIFigure                 matlab.ui.Figure
    FileMenu                 matlab.ui.container.Menu
    OpenMenu                 matlab.ui.container.Menu
    SaveMenu                 matlab.ui.container.Menu
    EditMenu                 matlab.ui.container.Menu
    SelectImageFolderButton  matlab.ui.control.Button
    Image                    matlab.ui.control.Image
    UIAxes                   matlab.ui.control.UIAxes
    SelectImageSliderLabel   matlab.ui.control.Label
    SelectImageSlider        matlab.ui.control.Slider
    FinishCheckBox           matlab.ui.control.CheckBox
  end

  % Callbacks that handle component events
  methods (Access = private)

    % Menu selected function: OpenMenu
    function FileOpen(app, event)
			[file,path] = uigetfile('*.jpg');           % Get file and path of image
			StoreFiles = {};                            % Create empty array to store file and path names 
			i = 0;                                      % Initialize variable 

			while not (isequal(file,0))                 % Loop through until user does not select a file
				i = i + 1;
				FullPathName = fullfile(path, file);
				StoreFiles{i,1} = FullPathName;           % Store path and file name in the next row of the array
				[file,path] = uigetfile('*.jpg');         % Ask user to select file again
				
				if isequal(file,0)                        % End loop once user finished selecting files
					disp(['User selected cancel']);
				end
				
			end

    end

    % Image clicked function: Image
    function OpenImage(app, event)
      
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.UIFigure = uifigure('Visible', 'off');
      app.UIFigure.Name = 'MATLAB App';
      app.FileMenu = uimenu(app.UIFigure);
      app.FileMenu.Text = 'File';
      app.OpenMenu = uimenu(app.FileMenu);
      app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @FileOpen, true);
      app.OpenMenu.Text = 'Open';
      app.SaveMenu = uimenu(app.FileMenu);
      app.SaveMenu.Text = 'Save';
      app.EditMenu = uimenu(app.UIFigure);
      app.EditMenu.Text = 'Edit';
      app.SelectImageFolderButton = uibutton(app.UIFigure, 'push');
      app.SelectImageFolderButton.Text = 'Select Image Folder';
      app.Image = uiimage(app.UIFigure);
      app.Image.ImageClickedFcn = createCallbackFcn(app, @OpenImage, true);
      app.UIAxes = uiaxes(app.UIFigure);
      title(app.UIAxes, 'Title')
      xlabel(app.UIAxes, 'X')
      ylabel(app.UIAxes, 'Y')
      app.SelectImageSliderLabel = uilabel(app.UIFigure);
      app.SelectImageSliderLabel.Text = 'Select Image #';
      app.SelectImageSlider = uislider(app.UIFigure);
      app.FinishCheckBox = uicheckbox(app.UIFigure);
      app.FinishCheckBox.Text = 'Finish';
      % Show the figure after all components are created
      app.UIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = Version1Project1

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.UIFigure)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.UIFigure)
    end
  end
end